classdef MGI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        CalculatriceRobManipUIFigure  matlab.ui.Figure
        CalculatriceRobdemanipLabel   matlab.ui.control.Label
        TabGroup                      matlab.ui.container.TabGroup
        MGITab                        matlab.ui.container.Tab
        l1EditFieldLabel              matlab.ui.control.Label
        l1                            matlab.ui.control.NumericEditField
        l2EditFieldLabel              matlab.ui.control.Label
        l2                            matlab.ui.control.NumericEditField
        xrEditFieldLabel              matlab.ui.control.Label
        xr                            matlab.ui.control.NumericEditField
        yrEditFieldLabel              matlab.ui.control.Label
        yr                            matlab.ui.control.NumericEditField
        CalculerleMGIButton           matlab.ui.control.Button
        CoudehautPanel                matlab.ui.container.Panel
        theta1_h                      matlab.ui.control.Label
        theta2_h                      matlab.ui.control.Label
        theta1_h_res                  matlab.ui.control.Label
        theta2_h_res                  matlab.ui.control.Label
        CoudebasPanel                 matlab.ui.container.Panel
        theta1_b                      matlab.ui.control.Label
        theta2_b                      matlab.ui.control.Label
        theta1_b_res                  matlab.ui.control.Label
        theta2_b_res                  matlab.ui.control.Label
        graphH                        matlab.ui.control.UIAxes
        graphB                        matlab.ui.control.UIAxes
        Switch_MGI                    matlab.ui.control.Switch
        l0xLabel_2                    matlab.ui.control.Label
        l0x_MGI                       matlab.ui.control.NumericEditField
        l0yLabel_2                    matlab.ui.control.Label
        l0y_MGI                       matlab.ui.control.NumericEditField
        MadebyTanguyLabel_2           matlab.ui.control.Label
        MGDTab                        matlab.ui.container.Tab
        l1EditFieldLabel_2            matlab.ui.control.Label
        l1_2                          matlab.ui.control.NumericEditField
        l2EditFieldLabel_2            matlab.ui.control.Label
        l2_2                          matlab.ui.control.NumericEditField
        Label                         matlab.ui.control.Label
        l1_3                          matlab.ui.control.NumericEditField
        Label_2                       matlab.ui.control.Label
        l2_3                          matlab.ui.control.NumericEditField
        CalculerleMGDButton           matlab.ui.control.Button
        graphMGD                      matlab.ui.control.UIAxes
        PositionatteintePanel         matlab.ui.container.Panel
        aff_xr                        matlab.ui.control.Label
        aff_yr                        matlab.ui.control.Label
        mgd_xr                        matlab.ui.control.Label
        mgd_yr                        matlab.ui.control.Label
        Switch                        matlab.ui.control.Switch
        l0Label_2                     matlab.ui.control.Label
        l0_2                          matlab.ui.control.NumericEditField
        CdMTab                        matlab.ui.container.Tab
        CalculerleCdMButton           matlab.ui.control.Button
        Switch_CDM                    matlab.ui.control.Switch
        Label_3                       matlab.ui.control.Label
        theta1_CDM                    matlab.ui.control.NumericEditField
        Label_4                       matlab.ui.control.Label
        theta2_CDM                    matlab.ui.control.NumericEditField
        PositionducentredemassePanel  matlab.ui.container.Panel
        aff_x_CDM                     matlab.ui.control.Label
        aff_y_CDM                     matlab.ui.control.Label
        x_CDM                         matlab.ui.control.Label
        y_CDM                         matlab.ui.control.Label
        l1EditFieldLabel_3            matlab.ui.control.Label
        l1_CDM                        matlab.ui.control.NumericEditField
        c1Label                       matlab.ui.control.Label
        c1_CDM                        matlab.ui.control.NumericEditField
        l0xLabel                      matlab.ui.control.Label
        l0x_CDM                       matlab.ui.control.NumericEditField
        c2Label                       matlab.ui.control.Label
        c2_CDM                        matlab.ui.control.NumericEditField
        m1Label                       matlab.ui.control.Label
        m1_CDM                        matlab.ui.control.NumericEditField
        m2Label                       matlab.ui.control.Label
        m2_CDM                        matlab.ui.control.NumericEditField
        r2Label                       matlab.ui.control.Label
        r2_CDM                        matlab.ui.control.NumericEditField
        r1Label                       matlab.ui.control.Label
        r1_CDM                        matlab.ui.control.NumericEditField
        Calculsoitavecr1etr2soitavecmletcLabel  matlab.ui.control.Label
        Switch_CDM_2                  matlab.ui.control.Switch
        r0yLabel                      matlab.ui.control.Label
        r0y_CDM                       matlab.ui.control.NumericEditField
        r0xLabel                      matlab.ui.control.Label
        r0x_CDM                       matlab.ui.control.NumericEditField
        m0xLabel                      matlab.ui.control.Label
        m0x_CDM                       matlab.ui.control.NumericEditField
        m0yLabel                      matlab.ui.control.Label
        m0y_CDM                       matlab.ui.control.NumericEditField
        c0xLabel                      matlab.ui.control.Label
        c0x_CDM                       matlab.ui.control.NumericEditField
        c0yLabel                      matlab.ui.control.Label
        c0y_CDM                       matlab.ui.control.NumericEditField
        l0yLabel                      matlab.ui.control.Label
        l0y_CDM                       matlab.ui.control.NumericEditField
        MadebyTanguyLabel             matlab.ui.control.Label
    end

    methods (Access = private)

        % Button pushed function: CalculerleMGIButton
        function CalculerleMGIButtonPushed(app, event)
            xr=app.xr.Value;
            yr=app.yr.Value;
            l1=app.l1.Value;
            l2=app.l2.Value;
            l0y=app.l0y_MGI.Value;
            l0x=app.l0x_MGI.Value;
            yr=yr-l0y;
            xr=xr-l0x;
            a = ((xr.^2)+(yr.^2)-((l1.^2)+(l2.^2)))/(2*l1*l2);
            if a <= 1 && a >= -1
                theta2pos = acos(a);
                theta2neg =-acos(a);
                
                b=(xr*(l1+l2*cos(theta2pos))+yr*l2*sin(theta2pos))/(xr.^2+yr.^2);
                c=(xr*(l1+l2*cos(theta2neg))+yr*l2*sin(theta2neg))/(xr.^2+yr.^2);
                
                if (b <= 1 && b >= -1)
                    if (c <= 1 && c >= -1)
                        theta1pos = acos(b);
                        theta1neg = acos(c);

                        plot(app.graphB,[[0;0;l1*cos(theta1pos)];[0;l1*cos(theta1pos);l1*cos(theta1pos)+l2*cos(theta1pos+theta2pos)]],...
                            [[0;l0y;l0y+l1*sin(theta1pos)];[l0y;l0y+l1*sin(theta1pos);l0y+l1*sin(theta1pos)+l2*sin(theta1pos+theta2pos)]]);
                        plot(app.graphH,[[0;0;l1*cos(theta1neg)];[0;l1*cos(theta1neg);l1*cos(theta1neg)+l2*cos(theta1neg+theta2neg)]],...
                            [[0;l0y;l0y+l1*sin(theta1neg)];[l0y;l0y+l1*sin(theta1neg);l0y+l1*sin(theta1neg)+l2*sin(theta1neg+theta2neg)]]);
                        
                            
                        if strcmp(app.Switch_MGI.Value,'Degrés')
                            app.theta1_b_res.Text=string(rad2deg(theta1pos));
                            app.theta2_b_res.Text=string(rad2deg(theta2pos));
                            
                            app.theta1_h_res.Text=string(rad2deg(theta1neg));
                            app.theta2_h_res.Text=string(rad2deg(theta2neg));
                        else
                            app.theta1_b_res.Text=string(theta1pos);
                            app.theta2_b_res.Text=string(theta2pos);
                            
                            app.theta1_h_res.Text=string(theta1neg);
                            app.theta2_h_res.Text=string(theta2neg);
                        end
                        
                            
                    else
                        %disp("Pos non atteignable θ1 coude bas invalide");
                    end
                else
                    %disp("Pos non atteignable θ1 coude haut invalide");
                end
            else
                %disp("Pos non atteignable θ2  invalide");
            end
                
            
        end

        % Button pushed function: CalculerleMGDButton
        function CalculerleMGDButtonPushed(app, event)
            l1=app.l1_2.Value;
            l2=app.l2_2.Value;
            l0=app.l0_2.Value;
            theta_1=app.l1_3.Value;
            theta_2=app.l2_3.Value;
            if strcmp(app.Switch.Value,'Degrés')
                theta_1=deg2rad(theta_1);
                theta_2=deg2rad(theta_2);
            end
            xr=l1*cos(theta_1)+l2*cos(theta_1+theta_2);
            yr=l1*sin(theta_1)+l2*sin(theta_1+theta_2)+l0;
            app.mgd_xr.Text=string(xr);
            app.mgd_yr.Text=string(yr);
            
            plot(app.graphMGD,[[0;0;l1*cos(theta_1)];[0;l1*cos(theta_1);l1*cos(theta_1)+l2*cos(theta_1+theta_2)]],...
                            [[0;l0;l0+l1*sin(theta_1)];[l0;l0+l1*sin(theta_1);l0+l1*sin(theta_1)+l2*sin(theta_1+theta_2)]]);
        end

        % Button pushed function: CalculerleCdMButton
        function CalculerleCdMButtonPushed(app, event)
            %l0=app.l0x_CDM.Value;
            l1=app.l1_CDM.Value;
            %l2=app.l2_CDM.Value;
            c1=app.c1_CDM.Value;
            c2=app.c2_CDM.Value;
            m1=app.m1_CDM.Value;
            m2=app.m2_CDM.Value;
            theta_1=app.theta1_CDM.Value;
            theta_2=app.theta2_CDM.Value;
            r1=app.r1_CDM.Value;
            r2=app.r2_CDM.Value;
            r0x=app.r0x_CDM.Value;
            r0y=app.r0y_CDM.Value;
            l0x=app.l0x_CDM.Value;
            l0y=app.l0y_CDM.Value;
            m0x=app.m0x_CDM.Value;
            m0y=app.m0y_CDM.Value;
            c0x=app.c0x_CDM.Value;
            c0y=app.c0y_CDM.Value;
            
            if strcmp(app.Switch_CDM.Value,'Degrés')
                theta_1=deg2rad(theta_1);
                theta_2=deg2rad(theta_2);
            end
            
            if strcmp(app.Switch_CDM_2.Value,'Gauche')
                r0y=(m0y*c0y+l0y*(m0x+m1+m2))/(m0x+m0y+m1+m2);
                r0x=(m0x*c0x+l0x*(m1+m2))/(m0x+m0y+m1+m2);
                r1=(m1*c1+m2*l1)/(m0x+m0y+m1+m2);
                r2=m2*c2/(m0x+m0y+m1+m2);
%                 x=(m1*c1+m2*l1)*cos(theta_1)+m2*c2*cos(theta_1+theta_2);
%                 x=x/(m1+m2);
%                 y=(m1*c1+m2*l1)*sin(theta_1)+m2*c2*sin(theta_1+theta_2);
%                 y=y/(m1+m2);
            end
            x=r0x+r1*cos(theta_1)+r2*cos(theta_1+theta_2);
            y=r0y+r1*sin(theta_1)+r2*sin(theta_1+theta_2);
            
            app.x_CDM.Text=string(x);
            app.y_CDM.Text=string(y);            
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.CalculatriceRobManipUIFigure = uifigure;
            app.CalculatriceRobManipUIFigure.Name = 'Calculatrice - Rob Manip';
            app.CalculatriceRobdemanipLabel = uilabel(app.CalculatriceRobManipUIFigure);
            app.CalculatriceRobdemanipLabel.Text = 'Calculatrice - Rob de manip';
            app.TabGroup = uitabgroup(app.CalculatriceRobManipUIFigure);
            app.MGITab = uitab(app.TabGroup);
            app.MGITab.Title = 'MGI';
            app.l1EditFieldLabel = uilabel(app.MGITab);
            app.l1EditFieldLabel.Text = 'l1';
            app.l1 = uieditfield(app.MGITab, 'numeric');
            app.l1.Limits = [0 Inf];
            app.l1.Value = 0.65;
            app.l2EditFieldLabel = uilabel(app.MGITab);
            app.l2EditFieldLabel.Text = 'l2';
            app.l2 = uieditfield(app.MGITab, 'numeric');
            app.l2.Limits = [0 Inf];
            app.l2.Value = 0.55;
            app.xrEditFieldLabel = uilabel(app.MGITab);
            app.xrEditFieldLabel.Text = 'xr';
            app.xr = uieditfield(app.MGITab, 'numeric');
            app.xr.Value = 0.5;
            app.yrEditFieldLabel = uilabel(app.MGITab);
            app.yrEditFieldLabel.Text = 'yr';
            app.yr = uieditfield(app.MGITab, 'numeric');
            app.yr.Value = 0.6;
            app.CalculerleMGIButton = uibutton(app.MGITab, 'push');
            app.CalculerleMGIButton.ButtonPushedFcn = createCallbackFcn(app, @CalculerleMGIButtonPushed, true);
            app.CalculerleMGIButton.Text = 'Calculer le MGI';
            app.CoudehautPanel = uipanel(app.MGITab);
            app.CoudehautPanel.Title = 'Coude haut';
            app.theta1_h = uilabel(app.CoudehautPanel);
            app.theta1_h.Text = 'θ1 :';
            app.theta2_h = uilabel(app.CoudehautPanel);
            app.theta2_h.Text = 'θ2 :';
            app.theta1_h_res = uilabel(app.CoudehautPanel);
            app.theta1_h_res.Text = '';
            app.theta2_h_res = uilabel(app.CoudehautPanel);
            app.theta2_h_res.Text = '';
            app.CoudebasPanel = uipanel(app.MGITab);
            app.CoudebasPanel.Title = 'Coude bas';
            app.theta1_b = uilabel(app.CoudebasPanel);
            app.theta1_b.Text = 'θ1 :';
            app.theta2_b = uilabel(app.CoudebasPanel);
            app.theta2_b.Text = 'θ2 :';
            app.theta1_b_res = uilabel(app.CoudebasPanel);
            app.theta1_b_res.Text = '';
            app.theta2_b_res = uilabel(app.CoudebasPanel);
            app.theta2_b_res.Text = '';
            app.graphH = uiaxes(app.MGITab);
            title(app.graphH, 'Position coude haut')
            app.graphB = uiaxes(app.MGITab);
            title(app.graphB, 'Position coude bas')
            app.Switch_MGI = uiswitch(app.MGITab, 'slider');
            app.Switch_MGI.Items = {'Degrés', 'Radians'};
            app.Switch_MGI.Value = 'Degrés';
            app.l0xLabel_2 = uilabel(app.MGITab);
            app.l0xLabel_2.Text = 'l0x';
            app.l0x_MGI = uieditfield(app.MGITab, 'numeric');
            app.l0x_MGI.Limits = [0 Inf];
            app.l0yLabel_2 = uilabel(app.MGITab);
            app.l0yLabel_2.Text = 'l0y';
            app.l0y_MGI = uieditfield(app.MGITab, 'numeric');
            app.l0y_MGI.Limits = [0 Inf];
            app.MadebyTanguyLabel_2 = uilabel(app.MGITab);
            app.MadebyTanguyLabel_2.Text = '/!\ : l0x ne s''affiche pas sur les graphiques mais il est bien pris en compte dans le calcul';
            app.MGDTab = uitab(app.TabGroup);
            app.MGDTab.Title = 'MGD';
            app.l1EditFieldLabel_2 = uilabel(app.MGDTab);
            app.l1EditFieldLabel_2.Text = 'l1';
            app.l1_2 = uieditfield(app.MGDTab, 'numeric');
            app.l1_2.Limits = [0 Inf];
            app.l1_2.Value = 0.65;
            app.l2EditFieldLabel_2 = uilabel(app.MGDTab);
            app.l2EditFieldLabel_2.Text = 'l2';
            app.l2_2 = uieditfield(app.MGDTab, 'numeric');
            app.l2_2.Limits = [0 Inf];
            app.l2_2.Value = 0.55;
            app.Label = uilabel(app.MGDTab);
            app.Label.Text = 'θ1';
            app.l1_3 = uieditfield(app.MGDTab, 'numeric');
            app.l1_3.Limits = [0 Inf];
            app.l1_3.Value = 70;
            app.Label_2 = uilabel(app.MGDTab);
            app.Label_2.Text = 'θ2';
            app.l2_3 = uieditfield(app.MGDTab, 'numeric');
            app.l2_3.Limits = [0 Inf];
            app.l2_3.Value = 32;
            app.CalculerleMGDButton = uibutton(app.MGDTab, 'push');
            app.CalculerleMGDButton.ButtonPushedFcn = createCallbackFcn(app, @CalculerleMGDButtonPushed, true);
            app.CalculerleMGDButton.Text = 'Calculer le MGD';
            app.graphMGD = uiaxes(app.MGDTab);
            title(app.graphMGD, 'Position atteinte')
            app.PositionatteintePanel = uipanel(app.MGDTab);
            app.PositionatteintePanel.Title = 'Position atteinte';
            app.aff_xr = uilabel(app.PositionatteintePanel);
            app.aff_xr.Text = 'xr :';
            app.aff_yr = uilabel(app.PositionatteintePanel);
            app.aff_yr.Text = 'yr :';
            app.mgd_xr = uilabel(app.PositionatteintePanel);
            app.mgd_xr.Text = '';
            app.mgd_yr = uilabel(app.PositionatteintePanel);
            app.mgd_yr.Text = '';
            app.Switch = uiswitch(app.MGDTab, 'slider');
            app.Switch.Items = {'Degrés', 'Radians'};
            app.Switch.Value = 'Degrés';
            app.l0Label_2 = uilabel(app.MGDTab);
            app.l0Label_2.Text = 'l0';
            app.l0_2 = uieditfield(app.MGDTab, 'numeric');
            app.l0_2.Limits = [0 Inf];
            app.CdMTab = uitab(app.TabGroup);
            app.CdMTab.Title = 'CdM';
            app.CalculerleCdMButton = uibutton(app.CdMTab, 'push');
            app.CalculerleCdMButton.ButtonPushedFcn = createCallbackFcn(app, @CalculerleCdMButtonPushed, true);
            app.CalculerleCdMButton.Text = 'Calculer le CdM';
            app.Switch_CDM = uiswitch(app.CdMTab, 'slider');
            app.Switch_CDM.Items = {'Degrés', 'Radians'};
            app.Switch_CDM.Value = 'Degrés';
            app.Label_3 = uilabel(app.CdMTab);
            app.Label_3.Text = 'θ1';
            app.theta1_CDM = uieditfield(app.CdMTab, 'numeric');
            app.theta1_CDM.Value = 70;
            app.Label_4 = uilabel(app.CdMTab);
            app.Label_4.Text = 'θ2';
            app.theta2_CDM = uieditfield(app.CdMTab, 'numeric');
            app.theta2_CDM.Value = 32;
            app.PositionducentredemassePanel = uipanel(app.CdMTab);
            app.PositionducentredemassePanel.Title = 'Position du centre de masse :';
            app.aff_x_CDM = uilabel(app.PositionducentredemassePanel);
            app.aff_x_CDM.Text = 'x :';
            app.aff_y_CDM = uilabel(app.PositionducentredemassePanel);
            app.aff_y_CDM.Text = 'y :';
            app.x_CDM = uilabel(app.PositionducentredemassePanel);
            app.x_CDM.Text = '';
            app.y_CDM = uilabel(app.PositionducentredemassePanel);
            app.y_CDM.Text = '';
            app.l1EditFieldLabel_3 = uilabel(app.CdMTab);
            app.l1EditFieldLabel_3.Text = 'l1';
            app.l1_CDM = uieditfield(app.CdMTab, 'numeric');
            app.l1_CDM.Limits = [0 Inf];
            app.c1Label = uilabel(app.CdMTab);
            app.c1Label.Text = 'c1';
            app.c1_CDM = uieditfield(app.CdMTab, 'numeric');
            app.c1_CDM.Limits = [0 Inf];
            app.l0xLabel = uilabel(app.CdMTab);
            app.l0xLabel.Text = 'l0x';
            app.l0x_CDM = uieditfield(app.CdMTab, 'numeric');
            app.l0x_CDM.Limits = [0 Inf];
            app.c2Label = uilabel(app.CdMTab);
            app.c2Label.Text = 'c2';
            app.c2_CDM = uieditfield(app.CdMTab, 'numeric');
            app.c2_CDM.Limits = [0 Inf];
            app.m1Label = uilabel(app.CdMTab);
            app.m1Label.Text = 'm1';
            app.m1_CDM = uieditfield(app.CdMTab, 'numeric');
            app.m1_CDM.Limits = [0 Inf];
            app.m2Label = uilabel(app.CdMTab);
            app.m2Label.Text = 'm2';
            app.m2_CDM = uieditfield(app.CdMTab, 'numeric');
            app.m2_CDM.Limits = [0 Inf];
            app.r2Label = uilabel(app.CdMTab);
            app.r2Label.Text = 'r2';
            app.r2_CDM = uieditfield(app.CdMTab, 'numeric');
            app.r2_CDM.Limits = [0 Inf];
            app.r1Label = uilabel(app.CdMTab);
            app.r1Label.Text = 'r1';
            app.r1_CDM = uieditfield(app.CdMTab, 'numeric');
            app.r1_CDM.Limits = [0 Inf];
            app.Calculsoitavecr1etr2soitavecmletcLabel = uilabel(app.CdMTab);
            app.Calculsoitavecr1etr2soitavecmletcLabel.Text = 'Calcul soit avec r1 et r2, soit avec m,l, etc.';
            app.Switch_CDM_2 = uiswitch(app.CdMTab, 'slider');
            app.Switch_CDM_2.Items = {'Gauche', 'Droit'};
            app.Switch_CDM_2.Value = 'Gauche';
            app.r0yLabel = uilabel(app.CdMTab);
            app.r0yLabel.Text = 'r0y';
            app.r0y_CDM = uieditfield(app.CdMTab, 'numeric');
            app.r0y_CDM.Limits = [0 Inf];
            app.r0xLabel = uilabel(app.CdMTab);
            app.r0xLabel.Text = 'r0x';
            app.r0x_CDM = uieditfield(app.CdMTab, 'numeric');
            app.r0x_CDM.Limits = [0 Inf];
            app.m0xLabel = uilabel(app.CdMTab);
            app.m0xLabel.Text = 'm0x';
            app.m0x_CDM = uieditfield(app.CdMTab, 'numeric');
            app.m0x_CDM.Limits = [0 Inf];
            app.m0yLabel = uilabel(app.CdMTab);
            app.m0yLabel.Text = 'm0y';
            app.m0y_CDM = uieditfield(app.CdMTab, 'numeric');
            app.m0y_CDM.Limits = [0 Inf];
            app.c0xLabel = uilabel(app.CdMTab);
            app.c0xLabel.Text = 'c0x';
            app.c0x_CDM = uieditfield(app.CdMTab, 'numeric');
            app.c0x_CDM.Limits = [0 Inf];
            app.c0yLabel = uilabel(app.CdMTab);
            app.c0yLabel.Text = 'c0y';
            app.c0y_CDM = uieditfield(app.CdMTab, 'numeric');
            app.c0y_CDM.Limits = [0 Inf];
            app.l0yLabel = uilabel(app.CdMTab);
            app.l0yLabel.Text = 'l0y';
            app.l0y_CDM = uieditfield(app.CdMTab, 'numeric');
            app.l0y_CDM.Limits = [0 Inf];
            app.MadebyTanguyLabel = uilabel(app.CalculatriceRobManipUIFigure);
            app.MadebyTanguyLabel.Text = 'Made by Tanguy';
        end
    end

    methods (Access = public)

        % Construct app
        function app = MGI

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.CalculatriceRobManipUIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.CalculatriceRobManipUIFigure)
        end
    end
end